classdef LIBSPreProcessV2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        SpectrometerDropDown         matlab.ui.control.DropDown
        SpectrometerDropDownLabel    matlab.ui.control.Label
        SaveFolderButton             matlab.ui.control.Button
        ProcessButton                matlab.ui.control.Button
        SelectAllCheckBox            matlab.ui.control.CheckBox
        ProcessinginfoTextArea       matlab.ui.control.TextArea
        ProcessinginfoTextAreaLabel  matlab.ui.control.Label
        FlielistListBox              matlab.ui.control.ListBox
        FlielistListBoxLabel         matlab.ui.control.Label
        LoadFolderButton             matlab.ui.control.Button
        CenterPanel                  matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        RightPanel                   matlab.ui.container.Panel
        fcSpinner                    matlab.ui.control.Spinner
        fcSpinnerLabel               matlab.ui.control.Label
        ParametersLabel              matlab.ui.control.Label
        ampSpinner                   matlab.ui.control.Spinner
        ampSpinnerLabel              matlab.ui.control.Label
        rSpinner                     matlab.ui.control.Spinner
        rSpinnerLabel                matlab.ui.control.Label
        dSpinner                     matlab.ui.control.Spinner
        dSpinnerLabel                matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end


    properties (Access = private)
        OriginalFolder % Original data folder path
        SaveFolder % Folder to save processed data
        Files % List of CSV files
        DefaultParam1_Type1 = 0.006; % Default value for parameter 1 for Type 1
        DefaultParam2_Type1 = 1; % Default value for parameter 2 for Type 1
        DefaultParam3_Type1 = 4; % Default value for parameter 3 for Type 1
        DefaultParam4_Type1 = 6; % Default value for parameter 4 for Type 1

        DefaultParam1_Type2 = 0.08; % Default value for parameter 1 for Type 2
        DefaultParam2_Type2 = 1; % Default value for parameter 2 for Type 2
        DefaultParam3_Type2 = 6; % Default value for parameter 3 for Type 2
        DefaultParam4_Type2 = 2; % Default value for parameter 4 for Type 2

    end

    methods (Access = private)
        % Function to load files from a folder
        function loadFiles(app, folder, fileType)
            if strcmp(fileType, 'Avantes')
                app.Files = dir(fullfile(folder, '*.csv'));
            else
                app.Files = dir(fullfile(folder, '*.mat'));
            end
            app.FlielistListBox.Items = {app.Files.name};
        end

        % Function to process spectra using user-defined function
        function processedData = processSpectra(app, data, param1, param2, param3, param4,spectrumType)
            wavelengths = data(:,1);
            spectra = data(:,2:end);
            processedSpectra = zeros(size(spectra));
            % Call user-defined processing function for each spectrum
            for i = 1:size(spectra, 2)
                if strcmp(spectrumType, 'Avantes')
                    [DEBR,Base,cost] = app.beads(spectra(:,i), param1, param2, param3, param4);
                    processedSpectra(:,i) = DEBR;
                else
                    M = 50;
                    y = spectra(:,i);
                    y_extended = [flipud(y(1:M)); y; flipud(y(end-M+1:end))]; % 镜像扩展
                    [DEBR_Extended,Base,cost] = app.beads(y_extended, param1, param2, param3, param4);
                    DEBR = DEBR_Extended(M+1:end-M);
                    processedSpectra(:,i) = DEBR;
                end
            end
            processedData = [wavelengths, processedSpectra];
        end


        % User-defined processing function
        function [x, f, cost] = beads(app, y, fc, d,r, amp)
            lam0 = 0.5*amp;
            lam1 = 3*amp;
            lam2 = 2*amp;
            % The following parameter may be altered.
            Nit = 15;       % Nit: Number of iterations
            pen = 'L1_v2';  % pen : penalty function for sparse derivative ('L1_v1' or 'L1_v2')
            EPS0 = 1e-6;    % cost smoothing parameter for x (small positive value)
            EPS1 = 1e-6;    % cost smoothing parameter for derivatives (small positive value)

            switch pen
                case 'L1_v1'
                    phi = @(x) sqrt(abs(x).^2 + EPS1);
                    wfun = @(x) 1./(sqrt(abs(x).^2 + EPS1));
                case 'L1_v2'
                    phi = @(x) abs(x) - EPS1 * log(abs(x) + EPS1);
                    wfun = @(x) 1./( abs(x) + EPS1);
                otherwise
                    disp('penalty must be L1_v1, L1_v2')
                    x = []; cost = []; f = [];
                    return
            end

            theta = @(x) sum(x(x>EPS0)) - r * sum(x(x<-EPS0)) ...
                + sum( (1+r)/(4*EPS0)*x(abs(x)<=EPS0).^2 ...
                + (1-r)/2 * x(abs(x)<=EPS0) + EPS0*(1+r)/4 );

            y = y(:);
            x = y;
            cost = zeros(1, Nit);
            N = length(y);
            [A, B] = app.BAfilt(d, fc, N);
            H = @(x) B*(A\x);
            e = ones(N-1, 1);
            D1 = spdiags([-e e], [0 1], N-1, N);
            D2 = spdiags([e -2*e e], 0:2, N-2, N);
            D = [D1;  D2];
            BTB = B'*B;

            w = [lam1 * ones(N-1, 1); lam2 * ones(N-2, 1)];
            b = (1-r)/2 * ones(N, 1);
            d = BTB * (A\y) - lam0 * A' * b;

            gamma = ones(N, 1);

            for i = 1:Nit

                Lambda = spdiags(w.*wfun(D*x), 0, 2*N-3, 2*N-3);

                k = abs(x) > EPS0;
                gamma(~k) = ((1 + r)/4) / abs(EPS0);
                gamma(k) = ((1 + r)/4) ./  abs(x(k));
                Gamma = spdiags(gamma, 0, N, N);

                M = 2 * lam0 * Gamma + D' * Lambda * D;
                x = A * ((BTB + A'*M*A)\d);

                cost(i) = 0.5 * sum(abs(H(y - x)).^2) + lam0 * theta(x) ...
                    + lam1 * sum(phi(diff(x))) + lam2 * sum(phi(diff(x, 2)));
            end

            f = y - x - H(y-x);

        end


        % --- local function ----

        function [A, B] = BAfilt(app,d, fc, N)
            % [A, B] = BAfilt(d, fc, N)
            %
            % Banded matrices for zero-phase high-pass filter.
            % The matrices are 'sparse' data type in MATLAB.
            %
            % INPUT
            %   d  : degree of filter is 2d (use d = 1 or 2)
            %   fc : cut-off frequency (normalized frequency, 0 < fc < 0.5)
            %   N  : length of signal

            b1 = [1 -1];
            for i = 1:d-1
                b1 = conv(b1, [-1 2 -1]);
            end
            b = conv(b1, [-1 1]);

            omc = 2*pi*fc;
            t = ((1-cos(omc))/(1+cos(omc)))^d;

            a = 1;
            for i = 1:d
                a = conv(a,[1 2 1]);
            end
            a = b + t*a;

            A = spdiags( a(ones(N, 1), :), -d:d, N, N);   % A: Symmetric banded matrix
            B = spdiags(b(ones(N, 1), :), -d:d, N, N);    % B: banded matrix
        end

        function results = updateSpinners(app)
            spectrumType = app.SpectrometerDropDown.Value;
            drawnow('limitrate');
            if strcmp(spectrumType, 'Avantes')
                app.fcSpinner.Value = app.DefaultParam1_Type1;
                app.dSpinner.Value = app.DefaultParam2_Type1;
                app.rSpinner.Value = app.DefaultParam3_Type1;
                app.ampSpinner.Value = app.DefaultParam4_Type1;
            else
                app.fcSpinner.Value = app.DefaultParam1_Type2;
                app.dSpinner.Value = app.DefaultParam2_Type2;
                app.rSpinner.Value = app.DefaultParam3_Type2;
                app.ampSpinner.Value = app.DefaultParam4_Type2;
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFolderButton
        function LoadFolderButtonPushed(app, event)
            folder = uigetdir;
            if folder
                app.OriginalFolder = folder;
                spectrumType = app.SpectrometerDropDown.Value;
                app.loadFiles(folder, spectrumType);
            end
        end

        % Button pushed function: SaveFolderButton
        function SaveFolderButtonPushed(app, event)
            folder = uigetdir;
            if folder
                app.SaveFolder = folder;
            end
        end

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)
            if isempty(app.OriginalFolder)
                app.ProcessinginfoTextArea.Value = 'Please load a folder with CSV files first.';
                return;
            end
            if isempty(app.SaveFolder)
                app.ProcessinginfoTextArea.Value = 'Please select a folder to save processed data.';
                return;
            end

            param1 = app.fcSpinner.Value;
            param2 = app.dSpinner.Value;
            param3 = app.rSpinner.Value;
            param4 = app.ampSpinner.Value;
            spectrumType = app.SpectrometerDropDown.Value;

            if app.SelectAllCheckBox.Value
                filesToProcess = app.Files;
            else
                selectedFile = app.FlielistListBox.Value;
                filesToProcess = app.Files(strcmp({app.Files.name}, selectedFile));
            end

            for i = 1:length(filesToProcess)

                filename = fullfile(app.OriginalFolder, filesToProcess(i).name);
                if strcmp(spectrumType, 'Avantes')
                    data = readmatrix(filename);
                else
                    matData = load(filename);
                    data =full(double(matData.Data)); % 将数据转换为全矩阵和双精度矩阵
                end

                % Process data
                processedData = app.processSpectra(data, param1, param2, param3, param4, spectrumType);

                % Plot original and processed data
                cla(app.UIAxes);
                hold(app.UIAxes, 'on');
                for j = 2:size(data, 2)
                    plot(app.UIAxes, data(:,1), data(:,j),'LineWidth', 1.5, 'DisplayName', ['Raw ' num2str(j-1)]);
                    plot(app.UIAxes, processedData(:,1),processedData(:,j), 'LineWidth', 1.5,'DisplayName', ['Processed ' num2str(j-1)]);
                end
                hold(app.UIAxes, 'off');
                legend(app.UIAxes, 'show');
                title(app.UIAxes, 'Raw and Processed Spectra','FontWeight', 'bold', 'FontName', 'Arial');

                % Save processed data
                [~, name, ~] = fileparts(filesToProcess(i).name);
                saveFilename = fullfile(app.SaveFolder, ['processed_' name '.csv']);
                writematrix(processedData, saveFilename);

                % Update log
                app.ProcessinginfoTextArea.Value = ['Processed and saved: ' saveFilename];
            end
        end

        % Drop down opening function: SpectrometerDropDown
        function SpectrometerDropDownOpening(app, event)
            app.updateSpinners();
        end

        % Value changed function: SpectrometerDropDown
        function SpectrometerDropDownValueChanged(app, event)

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {528, 528, 528};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {528, 528};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {202, '1x', 210};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadFolderButton = uibutton(app.LeftPanel, 'push');
            app.LoadFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFolderButtonPushed, true);
            app.LoadFolderButton.Text = 'Load Folder';
            app.FlielistListBoxLabel = uilabel(app.LeftPanel);
            app.FlielistListBoxLabel.Text = 'Flie list';
            app.FlielistListBox = uilistbox(app.LeftPanel);
            app.ProcessinginfoTextAreaLabel = uilabel(app.LeftPanel);
            app.ProcessinginfoTextAreaLabel.Text = 'Processing info';
            app.ProcessinginfoTextArea = uitextarea(app.LeftPanel);
            app.SelectAllCheckBox = uicheckbox(app.LeftPanel);
            app.SelectAllCheckBox.Text = 'Select All';
            app.ProcessButton = uibutton(app.LeftPanel, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            app.SaveFolderButton = uibutton(app.LeftPanel, 'push');
            app.SaveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFolderButtonPushed, true);
            app.SaveFolderButton.Text = 'Save Folder';
            app.SpectrometerDropDownLabel = uilabel(app.LeftPanel);
            app.SpectrometerDropDownLabel.Text = 'Spectrometer';
            app.SpectrometerDropDown = uidropdown(app.LeftPanel);
            app.SpectrometerDropDown.Items = {'Avantes', 'Shamrock'};
            app.SpectrometerDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SpectrometerDropDownOpening, true);
            app.SpectrometerDropDown.ValueChangedFcn = createCallbackFcn(app, @SpectrometerDropDownValueChanged, true);
            app.SpectrometerDropDown.Value = 'Avantes';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Processed spectra')
            xlabel(app.UIAxes, 'Wavelength (nm)')
            ylabel(app.UIAxes, 'Itensity (a.u.)')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            app.dSpinnerLabel = uilabel(app.RightPanel);
            app.dSpinnerLabel.Text = 'd';
            app.dSpinner = uispinner(app.RightPanel);
            app.dSpinner.Value = 1;
            app.rSpinnerLabel = uilabel(app.RightPanel);
            app.rSpinnerLabel.Text = 'r';
            app.rSpinner = uispinner(app.RightPanel);
            app.rSpinner.Value = 4;
            app.ampSpinnerLabel = uilabel(app.RightPanel);
            app.ampSpinnerLabel.Text = 'amp';
            app.ampSpinner = uispinner(app.RightPanel);
            app.ampSpinner.Value = 6;
            app.ParametersLabel = uilabel(app.RightPanel);
            app.ParametersLabel.Text = 'Parameters';
            app.fcSpinnerLabel = uilabel(app.RightPanel);
            app.fcSpinnerLabel.Text = 'fc';
            app.fcSpinner = uispinner(app.RightPanel);
            app.fcSpinner.Step = 0.001;
            app.fcSpinner.Value = 0.006;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIBSPreProcessV2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end